classdef Diatomix < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        FieldsTab             matlab.ui.container.Tab
        Laser1PolField        matlab.ui.control.EditField
        PolarisationLabel     matlab.ui.control.Label
        ILabel                matlab.ui.control.Label
        ELabel                matlab.ui.control.Label
        BLabel                matlab.ui.control.Label
        Laser1DirField        matlab.ui.control.EditField
        EDirField             matlab.ui.control.EditField
        BDirField             matlab.ui.control.EditField
        FieldLabel            matlab.ui.control.Label
        DirectionLabel        matlab.ui.control.Label
        BField                matlab.ui.control.EditField
        EField                matlab.ui.control.EditField
        Laser1Field           matlab.ui.control.EditField
        MoleculeTab           matlab.ui.container.Tab
        TextArea              matlab.ui.control.TextArea
        MoleculeEditField     matlab.ui.control.EditField
        MoleculeLabel         matlab.ui.control.Label
        Tree                  matlab.ui.container.CheckBoxTree
        RigidRotorNode        matlab.ui.container.TreeNode
        SpinSpinScalarNode    matlab.ui.container.TreeNode
        SpinSpinTensorNode    matlab.ui.container.TreeNode
        SpinRotationNode      matlab.ui.container.TreeNode
        NuclearElectricNode   matlab.ui.container.TreeNode
        BasisTab              matlab.ui.container.Tab
        TextArea_3            matlab.ui.control.TextArea
        BasisDropDown         matlab.ui.control.DropDown
        BasisDropDownLabel    matlab.ui.control.Label
        NmaxEditField         matlab.ui.control.NumericEditField
        NmaxEditFieldLabel    matlab.ui.control.Label
        PlotTab               matlab.ui.container.Tab
        N2EditField           matlab.ui.control.EditField
        N2EditFieldLabel      matlab.ui.control.Label
        N1EditField           matlab.ui.control.EditField
        N1EditFieldLabel      matlab.ui.control.Label
        TextArea_2            matlab.ui.control.TextArea
        ShowPointsCheckBox    matlab.ui.control.CheckBox
        Y2DropDown            matlab.ui.control.DropDown
        Y2DropDownLabel       matlab.ui.control.Label
        Y1DropDown            matlab.ui.control.DropDown
        Y1DropDownLabel       matlab.ui.control.Label
        PlotAdiabatsCheckBox  matlab.ui.control.CheckBox
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Ham % Hamiltonian
        Molecule
        UCBasis % Uncoupled Basis
    end
    
    methods (Access = private)

        function updatePlot(comp)
            ud = comp.UserData;
            ax = comp.UIAxes;

            delete(findobj(ax,'Type','line'))
            delete(findobj(ax,'Type','ConstantLine'))
            delete(findobj(ax,'Type','Scatter'))
            delete(findobj(ax,'Tag','update'))
            % spec = ud.spectrum(:,ud.selectStates);
            y1 = ud.yVar1.value(:,ud.selectStates);
            %Part for the first axis
            % yyaxis(ax,'left')
            % ax.YAxis(1).Color = "k";
            switch string(ud.yVar1.name)
                case "E"
                    set(ax, 'YDir','normal')
                case "E-<E>"
                    y1 = y1 - mean(y1,2);
                    set(ax, 'YDir','normal')
                case "µ"
                    set(ax, 'YDir','reverse')
                case "d"
                    set(ax, 'YDir','normal')
            end
            if ~isempty(y1)
                ps1 = plot(ax,ud.xVar.value,y1,'-', "Color",[1,1,1]*0.15, LineWidth=1);
                if comp.ShowPointsCheckBox.Value
                    hold(ax,'on');
                    plot(ax,ud.xVar.value,y1, ...
                        'o',MarkerSize=2, MarkerFaceColor=[1,1,1]*0.1, MarkerEdgeColor="none");
                    hold(ax,'off');
                end
            end
            % for p=1:length(ps)
            %     ps(p).DataTipTemplate(1).DataTipRows(2).Label="E(MHz)=";
            % %     ps(p).DataTipTemplate(1).DataTipRows(3).Label="E(MHz)=";
            % end
            xlabel(ax,sprintf("%s (%s)",ud.xVar.name,ud.xVar.unit))
            ylabel(ax,sprintf("%s (%s)",ud.yVar1.name,ud.yVar1.unit))
            title(ax,comp.Molecule.name, Interpreter="tex")
            xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            ylim(ax,'auto')
            legend(ax,'off');

            %Part for the second axis -- Not functional yet!
            % switch string(ud.currY2)
            %     case "E (MHz)"
            %         y2 = spec;
            %     case "E - <E> (MHz)"
            %         y2 = spec - mean(spec,2);
            %     case "µ"
            %         if isempty(ud.muEffective)
            %             mu = calculateMuEffective(comp);
            %             ud.muEffective = mu;
            %         end
            %         y2 = ud.muEffective(:,ud.selectStates);
            %         % y2=[];
            %     case "None"
            %         y2 = [];
            %         yyaxis(ax,'right')
            %         ax.YAxis(2).Visible='off';
            %         set(ax, 'YDir','reverse')
            % end
            % yyaxis(ax,'right')
            % if ~isempty(y2)
            %     ax.YAxis(2).Visible='on';
            %     ps2 = plot(ax,ud.xVar.value,y2, ...
            %     'o-', "Color","r",MarkerSize=2, LineWidth=1, MarkerFaceColor=[1,0,0], MarkerEdgeColor="none", Tag="Update");
            % end
            % xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            % ylabel(ax,string(comp.Y2DropDown.Value))
            % 
            % ax.YAxis(1).Visible='on'; %Set focus back to left axis
            comp.UserData = ud;
        end
        function [adiabat, adiabatStates, diabat, diabatStates] = calcAdiabat(comp, xIdx,yIdx)
            ud = comp.UserData;
            y = ud.spectrum;
            states = ud.currStates;
            x = ud.xVar.value;            
            [adiabat, adiabatStates, diabat, diabatStates] = findAdiabat(x,y,states, xIdx,yIdx);
        end
        function calculateSpectrum(comp)
            C = Constants;
            ud = comp.UserData;
            Ham = comp.Ham;
            nStates = Ham.Basis.NStates;
            energies = nan(length(ud.xVar.value),nStates);
            states = nan(length(ud.xVar.value),nStates,nStates);
            E.dir = ud.E.dir;
            B.dir = ud.B.dir;
            for k = 1:length(ud.xVar.value)
                if strcmp(ud.xVar.name,"B")
                    E.value = ud.E.value(1)*ud.E.scale; 
                    B.value = ud.B.value(k)*ud.B.scale;
                    ud.hamOpts.B = B; ud.hamOpts.E = E;
                    opts = namedargs2cell(ud.hamOpts);
                    H = Ham.makeHamiltonian(opts{:});
                elseif strcmp(ud.xVar.name,"E")
                    E.value = ud.E.value(k)*ud.E.scale;
                    B.value = ud.B.value(1)*ud.B.scale;
                    ud.hamOpts.B = B; ud.hamOpts.E = E;
                    opts = namedargs2cell(ud.hamOpts);
                    H = Ham.makeHamiltonian(opts{:});
                else
                end
                [V,D] = eig(full(H/C.h),'vector'); %no need to use sparse matrices for matrices smaller than 1000x1000. Divide by Plancks constant to get energy in Hz
                [d,sortIdx] = sort(real(D));
                energies(k,:) = d;        
                states(k,:,:) = V(:,sortIdx);
            end
            ud.currStates = states;
            ud.spectrum = energies*1e-6; %energies in MHz (xVar,States)

            ud.sortedStates=[];
            ud.muEffective = [];
            ud.inducedDipole = [];
            comp.UserData = ud;
            update(comp);
        end
        function updateHamiltonian(comp)
            ud = comp.UserData;

            Ham = Hamiltonian(Molecule = comp.Molecule, maxN=ud.Nmax);
            comp.Ham = Ham; 

            UCBasis = Ham.Basis;
            statesUCBasis = UCBasis.getStates('all');
            stateNames = ["N","mN","mi1","mi2"];
            ud.statesUCBasis = statesUCBasis(:,stateNames);
            comp.UITable.ColumnName = ["Comp",stateNames];
            ud.selectStates = ismember(ud.statesUCBasis.N,ud.selectN);
            
            ud.sortedStates=[];
            ud.muEffective = [];
            ud.inducedDipole = [];
            comp.UserData = ud;
        end
        function sortStates(comp)
            % sortStates(comp) calculates all adiabats and returns sorted
            % states
            ud = comp.UserData;
            if isempty(ud.sortedStates) %only do it if it hasnt been done before for this hamiltonian
                x = ud.xVar.value;
                sortedStates = nan(size(ud.currStates));
                for stIdx = 1:size(sortedStates,3)
                    [~, adiabatStates,~, ~ ] = calcAdiabat(comp, length(x),stIdx); %start from last state
                    sortedStates(:,:,stIdx) = adiabatStates;
                end
                ud.sortedStates = sortedStates;
            end
            comp.UserData = ud;
        end
        function mu = calculateMuEffective(comp)
            sortStates(comp); %first sort states as adiabats

            ud = comp.UserData;
            C = Constants;
            Hz = comp.Ham.zeeman;
            mu = nan(size(ud.spectrum));
            psis = ud.sortedStates;
            for x = 1:length(ud.xVar.value)
                psi = squeeze(psis(x,:,:));
                mu(x,:) = diag(conj(psi')*Hz*psi); %expectation value of zeeman part of the Hamiltonian
            end
            ud.muEffective = -real(mu)/C.muN;
            comp.UserData = ud;
        end
        function d = calculateInducedDipole(comp)
            sortStates(comp); %first sort states as adiabats

            ud = comp.UserData;
            C = Constants;
            H_dc = comp.Ham.dc_stark;
            Edir = ud.E.dir;
            H_stark = squeeze(Edir(1)*H_dc(1,:,:)+Edir(2)*H_dc(2,:,:)+Edir(3)*H_dc(3,:,:));            
            d = nan(size(ud.spectrum));
            psis = ud.sortedStates;
            for x = 1:length(ud.xVar.value)
                psi = squeeze(psis(x,:,:));
                d(x,:) = diag(conj(psi')*H_stark*psi); %expectation value of Stark part of the Hamiltonian
            end
            ud.inducedDipole = real(d)/C.D;
            comp.UserData = ud;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialise(comp)
                % fig = comp.Parent;
                addpath("Atoms\")
                addpath("Molecules\")
                
                ud = comp.UserData;
                ud.B.name = "B"; ud.B.unit = "G"; ud.B.scale = 1e-4; %from Tesla to Gauss
                ud.E.name = "E"; ud.E.unit = "V/cm"; ud.E.scale = 1e2; %from V/m to V/cm
                ud.I.name = "I"; ud.I.unit = ""; ud.I.scale = 1;
                ud.B.dir = eval(comp.BDirField.Value);
                ud.E.dir = eval(comp.EDirField.Value);
                ud.I.dir = eval(comp.Laser1DirField.Value);
                ud.B.value = eval(comp.BField.Value);
                ud.E.value = eval(comp.EField.Value);
                ud.selectN = 0;
                ud.xVar = ud.B;
                ud.Nmax = comp.NmaxEditField.Value;
                ud.hamOpts.useRigidRotor = 1;
                ud.hamOpts.useSpinSpinScalar = 1;
                ud.hamOpts.useSpinSpinTensor = 1;
                ud.hamOpts.useSpinRotation = 1;
                ud.hamOpts.useNuclearElectric = 1;
                comp.UserData = ud;

                comp.Molecule = KRb(40,87);
                updateHamiltonian(comp);
                calculateSpectrum(comp);
                update(comp);
                % collisionE = 0.1; %MHz
                updatePlot(comp);            
                % FieldValueChanged(comp); %gets field values and updates plot
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
            ud = comp.UserData;
            ax = comp.UIAxes;
            delete(findobj(ax,'Tag','update'))

            cStates = ud.currStates;
            cSpec = ud.yVar1.value(:,ud.selectStates);

            %snap to nearest point and highlight
            [~,xIdx] = min(abs(event.IntersectionPoint(1)-ud.xVar.value));
            [~,yIdx] = min(abs(event.IntersectionPoint(2)-cSpec(xIdx,:)));
            ax.NextPlot ="add";
            xline(ax,ud.xVar.value(xIdx),'--',color="k", Tag="update")
            yline(ax,cSpec(xIdx,yIdx),'--',color="k",Tag="update")
            scatter(ax,ud.xVar.value(xIdx), cSpec(xIdx,yIdx),50,'green','filled','o', MarkerFaceAlpha=0.5, Tag="update")
            
            %retrieve state composition of point and update table
            stateComp = round(squeeze(cStates(xIdx,:,yIdx)).^2,4);
            [statesCompTable, ~] = sortrows([array2table(stateComp',"VariableNames","Comp") , ud.statesUCBasis], "Comp","descend");
            comp.UITable.Data = statesCompTable(1:20,:);

            %find diabat and adiabat and highlight 
            if comp.PlotAdiabatsCheckBox.Value
                if any(strcmp(ud.yVar1.name, ["µ","d"]))
                adiabatLine = plot(ax,ud.xVar.value, cSpec(:,yIdx), '-', Color="red", LineWidth=1.5, ...
                    DisplayName="Adiabat",Tag="update");   
                legend(ax,[adiabatLine], Location="northeast");                
                else
                [adiabat,~, diabat,~] = calcAdiabat(comp,xIdx,yIdx);
                diabatLine = plot(ax,ud.xVar.value, diabat, '--', Color="blue", LineWidth=1.5, ...
                    DisplayName="Diabat",Tag="update");
                adiabatLine = plot(ax,ud.xVar.value, adiabat, '--', Color="red",LineWidth=1.5, ...
                    DisplayName="Adiabat",Tag="update");
                legend(ax,[adiabatLine,diabatLine], Location="northeast");
                end
            end
        end

        % Value changed function: BField, EField, Laser1Field
        function FieldValueChanged(comp, event)
            % ud = comp.UserData;
            % try 
            %     ud.B.value = eval(comp.BField.Value);
            %     ud.E.value = eval(comp.EField.Value);
            %     % ud.I.value = eval(comp.comp.Intensity.Value);
            %     if length(ud.B.value)>1
            %         ud.xVar = ud.B;
            %     elseif length(ud.E.value)>1
            %         ud.xVar = ud.E;
            %     else
            %         return
            %     end
            % catch
            %     error("Not a valid entry for fields!")
            %     return
            % end
            % NPoints = length(ud.B.value)*length(ud.E.value);
            % if NPoints > 302
            %     warning(sprintf("Do you really want to calculate %d points?",NPoints))
            %     return
            % end
            % comp.UserData = ud;
            update(comp);
            if comp.UserData.updateSuccess
                calculateSpectrum(comp);
                updatePlot(comp);            
            end
        end

        % Value changed function: BDirField, EDirField, Laser1DirField
        function FieldDirectionChanged(comp, event)
            ud = comp.UserData;
            % Bdir = eval(comp.BDirField.Value);
            Edir = eval(comp.EDirField.Value);
            Idir = eval(comp.Laser1DirField.Value);
            % assert(length(Bdir)==3 && sum(Bdir)~=0);
            assert(length(Edir)==3 && sum(Edir)~=0);
            assert(length(Idir)==3 && sum(Idir)~=0);

            % ud.B.dir=Bdir/norm(Bdir); 
            ud.E.dir=Edir/norm(Edir); 
            ud.I.dir=Idir/norm(Idir); 

            comp.UserData = ud;
            calculateSpectrum(comp);
            updatePlot(comp);   
        end

        % Value changed function: ShowPointsCheckBox
        function updatePlotCallback(comp, event)
            updatePlot(comp);   
        end

        % Callback function: MoleculeEditField, Tree
        function MoleculeChanged(comp, event)
            value = comp.MoleculeEditField.Value;
            try
                molecule = eval(value);
            catch
                error(sprintf("%s is not a valid Molecule Class", value));
            end
            comp.Molecule = molecule;
            
            hOpts.useRigidRotor = 0;
            hOpts.useSpinSpinScalar = 0;
            hOpts.useSpinSpinTensor = 0;
            hOpts.useSpinRotation = 0;
            hOpts.useNuclearElectric = 0;
            for l = 1:length(comp.Tree.CheckedNodes)
                t = comp.Tree.CheckedNodes(l);
                switch string(t.Text)
                    case "Rigid Rotor"
                        hOpts.useRigidRotor =1;
                    case "Spin-Spin (Scalar)"
                        hOpts.useSpinSpinScalar =1;
                    case "Spin-Spin (Tensor)"
                        hOpts.useSpinSpinTensor =1;
                    case "Spin-Rotation"
                        hOpts.useSpinRotation =1;
                    case "Nuclear Electric"
                        hOpts.useNuclearElectric =1;
                end
            end
            comp.UserData.hamOpts = hOpts;
            
            updateHamiltonian(comp);
            calculateSpectrum(comp);
            updatePlot(comp); 
        end

        % Value changed function: N1EditField, Y1DropDown, Y2DropDown
        function YAxisChanged(comp, event)
            update(comp);
            updatePlot(comp);   
        end

        % Callback function: not associated with a component
        function BasisChanged(comp, event)
            
        end

        % Value changed function: NmaxEditField
        function NmaxChanged(comp, event)
            Nmax = comp.NmaxEditField.Value;
            comp.UserData.Nmax = Nmax;
            assert(max(comp.UserData.selectN)<= Nmax);

            % update(comp);
            updateHamiltonian(comp);
            calculateSpectrum(comp);
            updatePlot(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           comp.UserData.updateSuccess = 0;
           ud = comp.UserData;

            %update xVar -- Has to be first!
           try 
                ud.B.value = eval(comp.BField.Value);
                ud.E.value = eval(comp.EField.Value);
                % ud.I.value = eval(comp.comp.Intensity.Value);
                if length(ud.B.value)>1
                    ud.xVar = ud.B;
                elseif length(ud.E.value)>1
                    ud.xVar = ud.E;
                else
                    return
                end
            catch
                error("Not a valid entry for fields!")
                return
            end
            NPoints = length(ud.B.value)*length(ud.E.value);
            if NPoints > 302
                warning(sprintf("Do you really want to calculate %d points?",NPoints))
                return
            end
            ud.Nmax = comp.NmaxEditField.Value;

            %update N Manifold to plot
           selectN = int32(eval(comp.N1EditField.Value));
           assert(max(selectN)<=ud.Nmax);
           if any(ud.selectN ~= selectN)
           ud.selectN = selectN;
           ud.selectStates = ismember(ud.statesUCBasis.N,selectN);
           end

           comp.UserData = ud;

            % update yVar
           switch string(comp.Y1DropDown.Value)
                case "E"
                    yVar1.name = "E";
                    yVar1.unit = "MHz";
                    yVar1.value = comp.UserData.spectrum;
                case "E - <E>"
                    yVar1.name = "E-<E>";
                    yVar1.unit = "MHz";
                    yVar1.value = comp.UserData.spectrum;
                case "µ"
                    yVar1.name = "µ";
                    yVar1.unit = "µ0";
                    if isempty(comp.UserData.muEffective)
                        calculateMuEffective(comp);
                    end
                    yVar1.value = comp.UserData.muEffective;
                case "d"
                    yVar1.name = "d";
                    yVar1.unit = "d0";
                    if isempty(comp.UserData.inducedDipole)
                        calculateInducedDipole(comp);
                    end
                    yVar1.value = comp.UserData.inducedDipole;
           end
           comp.UserData.yVar1 = yVar1;
            
           % -------- Add extra stuff if needed ---------%


            comp.UserData.updateSuccess = 1;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 918 559];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'E (MHz)')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Comp'; 'N'; 'mN'; 'mi1'; 'mi2'};
            comp.UITable.RowName = {};
            comp.UITable.SelectionType = 'row';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.TabGroup = uitabgroup(comp.GridLayout2);
            comp.FieldsTab = uitab(comp.TabGroup);
            comp.FieldsTab.Title = 'Fields';
            comp.Laser1Field = uieditfield(comp.FieldsTab, 'text');
            comp.Laser1Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Laser1Field.Enable = 'off';
            comp.Laser1Field.Tooltip = {'Coming Soon'};
            comp.Laser1Field.Value = '0';
            comp.EField = uieditfield(comp.FieldsTab, 'text');
            comp.EField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.EField.Value = '0';
            comp.BField = uieditfield(comp.FieldsTab, 'text');
            comp.BField.CharacterLimits = [1 Inf];
            comp.BField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.BField.Value = '0.1:0.5:50';
            comp.DirectionLabel = uilabel(comp.FieldsTab);
            comp.DirectionLabel.Text = 'Direction';
            comp.FieldLabel = uilabel(comp.FieldsTab);
            comp.FieldLabel.Text = 'Field';
            comp.BDirField = uieditfield(comp.FieldsTab, 'text');
            comp.BDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.BDirField.Editable = 'off';
            comp.BDirField.Value = '[0,0,1]';
            comp.EDirField = uieditfield(comp.FieldsTab, 'text');
            comp.EDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.EDirField.Value = '[0,1,0]';
            comp.Laser1DirField = uieditfield(comp.FieldsTab, 'text');
            comp.Laser1DirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.Laser1DirField.Enable = 'off';
            comp.Laser1DirField.Tooltip = {'Coming Soon'};
            comp.Laser1DirField.Value = '[0,0,1]';
            comp.BLabel = uilabel(comp.FieldsTab);
            comp.BLabel.Text = 'B';
            comp.ELabel = uilabel(comp.FieldsTab);
            comp.ELabel.Text = 'E';
            comp.ILabel = uilabel(comp.FieldsTab);
            comp.ILabel.Text = 'I';
            comp.PolarisationLabel = uilabel(comp.FieldsTab);
            comp.PolarisationLabel.Text = 'Polarisation';
            comp.Laser1PolField = uieditfield(comp.FieldsTab, 'text');
            comp.Laser1PolField.Enable = 'off';
            comp.Laser1PolField.Tooltip = {'Coming Soon'};
            comp.Laser1PolField.Value = '[0,0,1]';
            comp.MoleculeTab = uitab(comp.TabGroup);
            comp.MoleculeTab.Title = 'Molecule';
            comp.Tree = uitree(comp.MoleculeTab, 'checkbox');
            comp.Tree.Tooltip = {''};
            comp.RigidRotorNode = uitreenode(comp.Tree);
            comp.RigidRotorNode.Text = 'Rigid Rotor';
            comp.SpinSpinScalarNode = uitreenode(comp.Tree);
            comp.SpinSpinScalarNode.Text = 'Spin-Spin (Scalar)';
            comp.SpinSpinTensorNode = uitreenode(comp.Tree);
            comp.SpinSpinTensorNode.Text = 'Spin-Spin (Tensor)';
            comp.SpinRotationNode = uitreenode(comp.Tree);
            comp.SpinRotationNode.Text = 'Spin-Rotation';
            comp.NuclearElectricNode = uitreenode(comp.Tree);
            comp.NuclearElectricNode.Text = 'Nuclear Electric';
            % Assign Checked Nodes
            comp.Tree.CheckedNodes = [comp.RigidRotorNode, comp.SpinSpinScalarNode, comp.SpinSpinTensorNode, comp.SpinRotationNode, comp.NuclearElectricNode];
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @MoleculeChanged, true);
            comp.MoleculeLabel = uilabel(comp.MoleculeTab);
            comp.MoleculeLabel.Text = 'Molecule:';
            comp.MoleculeEditField = uieditfield(comp.MoleculeTab, 'text');
            comp.MoleculeEditField.CharacterLimits = [0 15];
            comp.MoleculeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MoleculeChanged, true);
            comp.MoleculeEditField.Placeholder = 'Molecule';
            comp.MoleculeEditField.Value = 'KRb(40,87)';
            comp.TextArea = uitextarea(comp.MoleculeTab);
            comp.TextArea.Editable = 'off';
            comp.TextArea.Enable = 'off';
            comp.TextArea.Visible = 'off';
            comp.BasisTab = uitab(comp.TabGroup);
            comp.BasisTab.Title = 'Basis';
            comp.NmaxEditFieldLabel = uilabel(comp.BasisTab);
            comp.NmaxEditFieldLabel.Text = 'Nmax';
            comp.NmaxEditField = uieditfield(comp.BasisTab, 'numeric');
            comp.NmaxEditField.Limits = [0 5];
            comp.NmaxEditField.RoundFractionalValues = 'on';
            comp.NmaxEditField.ValueDisplayFormat = '%.0f';
            comp.NmaxEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NmaxChanged, true);
            comp.NmaxEditField.Value = 1;
            comp.BasisDropDownLabel = uilabel(comp.BasisTab);
            comp.BasisDropDownLabel.Enable = 'off';
            comp.BasisDropDownLabel.Text = 'Basis';
            comp.BasisDropDown = uidropdown(comp.BasisTab);
            comp.BasisDropDown.Items = {'Uncoupled', 'Spin-Coupled', 'Fully-Coupled'};
            comp.BasisDropDown.Enable = 'off';
            comp.BasisDropDown.Value = 'Uncoupled';
            comp.TextArea_3 = uitextarea(comp.BasisTab);
            comp.TextArea_3.Editable = 'off';
            comp.TextArea_3.Enable = 'off';
            comp.TextArea_3.Visible = 'off';
            comp.PlotTab = uitab(comp.TabGroup);
            comp.PlotTab.Title = 'Plot';
            comp.PlotAdiabatsCheckBox = uicheckbox(comp.PlotTab);
            comp.PlotAdiabatsCheckBox.Text = 'Plot Adiabats';
            comp.Y1DropDownLabel = uilabel(comp.PlotTab);
            comp.Y1DropDownLabel.Text = 'Y1';
            comp.Y1DropDown = uidropdown(comp.PlotTab);
            comp.Y1DropDown.Items = {'E', 'E - <E>', 'µ', 'd'};
            comp.Y1DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y1DropDown.Value = 'E';
            comp.Y2DropDownLabel = uilabel(comp.PlotTab);
            comp.Y2DropDownLabel.Enable = 'off';
            comp.Y2DropDownLabel.Visible = 'off';
            comp.Y2DropDownLabel.Text = 'Y2';
            comp.Y2DropDown = uidropdown(comp.PlotTab);
            comp.Y2DropDown.Items = {'E', 'E - <E>', 'µ', 'd'};
            comp.Y2DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.Y2DropDown.Enable = 'off';
            comp.Y2DropDown.Visible = 'off';
            comp.Y2DropDown.Value = 'E';
            comp.ShowPointsCheckBox = uicheckbox(comp.PlotTab);
            comp.ShowPointsCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlotCallback, true);
            comp.ShowPointsCheckBox.Text = 'Show Points';
            comp.TextArea_2 = uitextarea(comp.PlotTab);
            comp.TextArea_2.Editable = 'off';
            comp.TextArea_2.Visible = 'off';
            comp.N1EditFieldLabel = uilabel(comp.PlotTab);
            comp.N1EditFieldLabel.Text = 'N1';
            comp.N1EditField = uieditfield(comp.PlotTab, 'text');
            comp.N1EditField.CharacterLimits = [1 10];
            comp.N1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @YAxisChanged, true);
            comp.N1EditField.Value = '0';
            comp.N2EditFieldLabel = uilabel(comp.PlotTab);
            comp.N2EditFieldLabel.Enable = 'off';
            comp.N2EditFieldLabel.Text = 'N2';
            comp.N2EditField = uieditfield(comp.PlotTab, 'text');
            comp.N2EditField.Enable = 'off';
            % Execute the startup function
            initialise(comp)
        end
    end
end